classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SweatPoreDetectorUIFigure   matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ExperimentModeListBoxLabel  matlab.ui.control.Label
        ExperimentModeListBox       matlab.ui.control.ListBox
        LoadInputButton             matlab.ui.control.Button
        PoreSizePanel               matlab.ui.container.Panel
        MinEditFieldLabel           matlab.ui.control.Label
        pore_size_min               matlab.ui.control.NumericEditField
        MaxEditField_2Label         matlab.ui.control.Label
        pore_size_max               matlab.ui.control.NumericEditField
        StartButton                 matlab.ui.control.Button
        histogram_buttom            matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        InputImageAxes              matlab.ui.control.UIAxes
        EnhancedImageAxes           matlab.ui.control.UIAxes
        PredictionAxes              matlab.ui.control.UIAxes
        FrameIndex_Slider_Label     matlab.ui.control.Label
        FrameIndex_Slider           matlab.ui.control.Slider
        Area_Label                  matlab.ui.control.Label
        Area_EditField              matlab.ui.control.NumericEditField
        PoreCount_Label             matlab.ui.control.Label
        PoreCount_EditField         matlab.ui.control.NumericEditField
        HistogramAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
            name = ''
            path
            Area
            PC  
            start = false
            show_histogram = false
    end
    
    methods (Access = public)
        
        function showing_the_results(app)            
            frame_index = round(app.FrameIndex_Slider.Value);
            
            if ~isempty(app.name) && app.start
                im = imread([app.path,app.name], frame_index);
                imshow(im, [] , 'Parent', app.InputImageAxes)
                
                UserInfo.pore_size_range = [app.pore_size_min.Value, app.pore_size_max.Value];
                [prediction, im_enhanced] = segmentation(im, UserInfo);
                Results = detecting_objects(prediction);
                
                sz = size(prediction);
                app.Area_EditField.Value      = (sum(Results.Area)*100)/(sz(1)*sz(2));
                app.PoreCount_EditField.Value = Results.PC;
                                            
                imshow(im_enhanced, [] , 'Parent', app.EnhancedImageAxes)
                imshow(prediction,  'Parent', app.PredictionAxes)
                
                if app.show_histogram                    
                    histogram(app.HistogramAxes, Results.Area);                    
                end
            end
        end
    end
      

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SweatPoreDetectorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {496, 496};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {164, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: ExperimentModeListBox
        function ExperimentModeListBoxValueChanged(app, event)
            if strcmp(app.ExperimentModeListBox.Value , 'Single TIF Frame')
                app.FrameIndex_Slider.Visible       = true;
                app.FrameIndex_Slider_Label.Visible = true;
                
                app.Area_EditField.Visible = true;
                app.Area_Label.Visible     = true;
                
                app.PoreCount_EditField.Visible = true;
                app.PoreCount_Label.Visible     = true;                                   
                
                if app.show_histogram
                    app.HistogramAxes.Visible = true;
                end
                
                showing_the_results(app)  
                
            else
                app.FrameIndex_Slider.Visible       = false;
                app.FrameIndex_Slider_Label.Visible = false;      
                
                app.Area_EditField.Visible = false;
                app.Area_Label.Visible     = false;
                
                app.PoreCount_EditField.Visible = false;
                app.PoreCount_Label.Visible     = false; 
                
                app.HistogramAxes.Visible = false;
                if ~isempty(app.HistogramAxes.Children); app.HistogramAxes.Children.Visible = false;  end
            end
        end

        % Button pushed function: LoadInputButton
        function LoadInputButtonPushed(app, event)
            if any(strcmp(app.ExperimentModeListBox.Value, {'Single TIF Frame','TIF Video'}))
                TYPE = 'video.tif';
            else
                TYPE = 'video.ats';
            end
            [app.name,app.path] = uigetfile(['H:\Datasets\FLIR Datasets\sample\Rec-000020\',TYPE],'Select Input Video');
            
            if app.name
                app.LoadInputButton.BackgroundColor = [1.00, 0.41, 0.16];
            end
        end

        % Callback function
        function FrameIndexSliderValueChanged(app, event)
            
        end

        % Value changing function: FrameIndex_Slider
        function FrameIndex_SliderValueChanging(app, event)
            frame_index = round(event.Value);
            event.Source.Value = frame_index;
            showing_the_results(app)                    
        end

        % Value changed function: pore_size_min
        function pore_size_minValueChanged(app, event)
            showing_the_results(app) 
        end

        % Value changed function: pore_size_max
        function pore_size_maxValueChanged(app, event)
            showing_the_results(app)             
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            if app.name
                app.FrameIndex_Slider.Enable = true;
                app.start = true;
                app.StartButton.BackgroundColor = [1.00, 0.41, 0.16];
                if strcmp(app.ExperimentModeListBox.Value, 'TIF Video')                
                    [app.Area , app.PC] = save_video_from_video_tif(app.path, app.name);
                elseif strcmp(app.ExperimentModeListBox.Value, 'Single TIF Frame')                                                
                    app.FrameIndex_Slider.Limits = [1,length(imfinfo([app.path,app.name]))];
                    showing_the_results(app)
                end
            end
            
        end

        % Button pushed function: histogram_buttom
        function histogram_buttomButtonPushed(app, event)
            if app.show_histogram == true
                app.show_histogram = false;
                app.HistogramAxes.Visible = false;
                if ~isempty(app.HistogramAxes.Children); app.HistogramAxes.Children.Visible = false; end
            else
                app.show_histogram = true;
                app.HistogramAxes.Visible = true;
                
                showing_the_results(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SweatPoreDetectorUIFigure = uifigure('Visible', 'off');
            app.SweatPoreDetectorUIFigure.Name = 'SweatPoreDetector';
            app.SweatPoreDetectorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SweatPoreDetectorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExperimentModeListBoxLabel = uilabel(app.LeftPanel);
            app.ExperimentModeListBoxLabel.Text = 'Experiment Mode';
            app.ExperimentModeListBox = uilistbox(app.LeftPanel);
            app.ExperimentModeListBox.Items = {'Single TIF Frame', 'TIF Video', 'ATS Video'};
            app.ExperimentModeListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentModeListBoxValueChanged, true);
            app.ExperimentModeListBox.Value = 'Single TIF Frame';
            app.LoadInputButton = uibutton(app.LeftPanel, 'push');
            app.LoadInputButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInputButtonPushed, true);
            app.LoadInputButton.Text = 'Load Input';
            app.PoreSizePanel = uipanel(app.LeftPanel);
            app.PoreSizePanel.Title = 'Pore Size';
            app.MinEditFieldLabel = uilabel(app.PoreSizePanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.pore_size_min = uieditfield(app.PoreSizePanel, 'numeric');
            app.pore_size_min.Limits = [1 Inf];
            app.pore_size_min.RoundFractionalValues = 'on';
            app.pore_size_min.ValueChangedFcn = createCallbackFcn(app, @pore_size_minValueChanged, true);
            app.pore_size_min.Value = 1;
            app.MaxEditField_2Label = uilabel(app.PoreSizePanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.pore_size_max = uieditfield(app.PoreSizePanel, 'numeric');
            app.pore_size_max.Limits = [1 Inf];
            app.pore_size_max.RoundFractionalValues = 'on';
            app.pore_size_max.ValueChangedFcn = createCallbackFcn(app, @pore_size_maxValueChanged, true);
            app.pore_size_max.Value = 15;
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.histogram_buttom = uibutton(app.LeftPanel, 'push');
            app.histogram_buttom.ButtonPushedFcn = createCallbackFcn(app, @histogram_buttomButtonPushed, true);
            app.histogram_buttom.Icon = 'histogramlogo.png';
            app.histogram_buttom.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.InputImageAxes = uiaxes(app.RightPanel);
            title(app.InputImageAxes, 'Input Image')
            xlabel(app.InputImageAxes, '')
            ylabel(app.InputImageAxes, '')
            app.InputImageAxes.XTick = [];
            app.InputImageAxes.YTick = [];
            app.EnhancedImageAxes = uiaxes(app.RightPanel);
            title(app.EnhancedImageAxes, 'Enhanced Image')
            xlabel(app.EnhancedImageAxes, '')
            ylabel(app.EnhancedImageAxes, '')
            app.EnhancedImageAxes.XTick = [];
            app.EnhancedImageAxes.YTick = [];
            app.PredictionAxes = uiaxes(app.RightPanel);
            title(app.PredictionAxes, 'Prediction')
            xlabel(app.PredictionAxes, '')
            ylabel(app.PredictionAxes, '')
            app.PredictionAxes.XTick = [];
            app.PredictionAxes.XTickLabel = '';
            app.PredictionAxes.YTick = [];
            app.FrameIndex_Slider_Label = uilabel(app.RightPanel);
            app.FrameIndex_Slider_Label.Text = 'Frame Index';
            app.FrameIndex_Slider = uislider(app.RightPanel);
            app.FrameIndex_Slider.ValueChangingFcn = createCallbackFcn(app, @FrameIndex_SliderValueChanging, true);
            app.FrameIndex_Slider.Enable = 'off';
            app.FrameIndex_Slider.Value = 20;
            app.Area_Label = uilabel(app.RightPanel);
            app.Area_Label.Text = 'Area (%)';
            app.Area_EditField = uieditfield(app.RightPanel, 'numeric');
            app.Area_EditField.ValueDisplayFormat = '%.4f';
            app.PoreCount_Label = uilabel(app.RightPanel);
            app.PoreCount_Label.Text = 'Pore Count';
            app.PoreCount_EditField = uieditfield(app.RightPanel, 'numeric');
            app.HistogramAxes = uiaxes(app.RightPanel);
            title(app.HistogramAxes, 'Histogram of sweat Pore Size Distribution')
            xlabel(app.HistogramAxes, 'Area (pixel)')
            ylabel(app.HistogramAxes, 'Number of Detected Pores')
            app.HistogramAxes.Visible = 'off';
            % Show the figure after all components are created
            app.SweatPoreDetectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SweatPoreDetectorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SweatPoreDetectorUIFigure)
        end
    end
end