classdef app_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputPanel                     matlab.ui.container.Panel
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        nameEditFieldLabel              matlab.ui.control.Label
        nameEditField                   matlab.ui.control.EditField
        csvLabel                        matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        PREDICTAIONPanel                matlab.ui.container.Panel
        MaximumPoreSizeEditFieldLabel   matlab.ui.control.Label
        MaximumPoreSizeEditField        matlab.ui.control.NumericEditField
        SegmentationThresholdEditFieldLabel  matlab.ui.control.Label
        SegmentationThresholdEditField  matlab.ui.control.NumericEditField
        FigurePanel                     matlab.ui.container.Panel
        SelectForegroundButton          matlab.ui.control.Button
        StartTheAnalysisButton          matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        LightBulbDropDownLabel          matlab.ui.control.Label
        LightBulbDropDown               matlab.ui.control.DropDown
        SelectLightBulbAreaButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        background = 0;
        frame;
        lightBulb = 0;
    end
    
    methods (Access = public)
        
        function segmenting_pores(app)
                       
            % Mitigating the Effect of LED When it's On
            image = mitigating_Effect_of_LED(app.frame, app.background);
            
            % Segmentation
            image = func_normalize(image,1);
            [prediction, frame_enhanced] = segmentation2( struct('im',image , 'binarization_threshold',app.SegmentationThresholdEditField.Value , 'ClipLimit',0.2) );
            
            % Post Processing
            prediction = Post_Processing( struct('prediction', prediction  ,  'background',app.background  ,  'max_obj_size',app.MaximumPoreSizeEditField.Value) );
            
            app.FigurePanel.AutoResizeChildren = 'off';
            ax(1) = subplot(1,2,1,'Parent',app.FigurePanel); imshow(frame_enhanced,'Parent',ax(1));
            ax(2) = subplot(1,2,2,'Parent',app.FigurePanel); imshow(prediction,'Parent',ax(2));
            linkaxes(ax)
        end
        
        function start_the_analysis(app)                       
             assignin('base','Directory_output' , [replace(app.DirectoryEditField.Value,'\','/'),'/',app.nameEditField.Value,'.csv'] );
             assignin('base','name'        , app.nameEditField.Value);
             assignin('base','maxPore_size' , app.MaximumPoreSizeEditField.Value);  
             assignin('base','binarization_threshold' , app.SegmentationThresholdEditField.Value);               
             assignin('base','background' , app.background); 
             assignin('base','lightBulb_detection_mode' , app.LightBulbDropDown.Value); 
             assignin('base','lightBulb_area' , app.lightBulb); 
             delete(app)
        end
        
        function im = normalize_frame(app)
             im = app.frame;
             MAX = max(max(im(1:300,:)));
             im(im > MAX) = MAX;
             im = func_normalize(im,1);            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.frame = evalin('base','rir_filter_input');             
             app.PREDICTAIONPanel.Visible = false;
             app.StartTheAnalysisButton.Visible = false;
             app.SelectLightBulbAreaButton.Visible = false;
        end

        % Callback function: BrowseButton, DirectoryEditField
        function ChangeButtonPushed(app, event)
            app.DirectoryEditField.Value = uigetdir(app.DirectoryEditField.Value," Please select the directory for saving the output csv file");
        end

        % Callback function: StartTheAnalysisButton, UIFigure
        function UIFigureCloseRequest(app, event)
            start_the_analysis(app)
        end

        % Value changed function: MaximumPoreSizeEditField, 
        % SegmentationThresholdEditField
        function MaximumPoreSizeEditFieldValueChanged(app, event)
            segmenting_pores(app)
        end

        % Button pushed function: SelectForegroundButton
        function SelectForegroundButtonPushed(app, event)
             figure 
%              im = app.frame;
%              MAX = max(max(im(1:300,:)));
%              im(im > MAX) = MAX;
%              im = func_normalize(im,1);
             app.background = ~roipoly(normalize_frame(app));   
             close                     
                      
             app.SelectForegroundButton.FontColor = [0.47,0.67,0.19];
             app.PREDICTAIONPanel.Visible = true;
             app.StartTheAnalysisButton.Visible = true;
             segmenting_pores(app)            
        end

        % Button pushed function: SelectLightBulbAreaButton
        function SelectLightBulbAreaButtonPushed(app, event)
             figure 
             imshow(normalize_frame(app),[])
%              app.lightBulb = roipoly(normalize_frame(app)); 
             app.lightBulb = createMask(drawrectangle('Color','r','DrawingArea','auto'));
             close              
             app.SelectLightBulbAreaButton.FontColor = [0.47,0.67,0.19];
        end

        % Value changed function: LightBulbDropDown
        function LightBulbDropDownValueChanged(app, event)
            if app.LightBulbDropDown.Value == "Manual ROI"
                app.SelectLightBulbAreaButton.Visible = true;                              
            else
                app.SelectLightBulbAreaButton.Visible = false;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.DirectoryEditFieldLabel = uilabel(app.OutputPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.OutputPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.DirectoryEditField.Value = 'C:\Users\Sweatporeactivation\Documents\sweat_pore_prj\outputs';
            app.nameEditFieldLabel = uilabel(app.OutputPanel);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.OutputPanel, 'text');
            app.nameEditField.Value = 'subject';
            app.csvLabel = uilabel(app.OutputPanel);
            app.csvLabel.Text = '.csv';
            app.BrowseButton = uibutton(app.OutputPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PREDICTAIONPanel = uipanel(app.UIFigure);
            app.PREDICTAIONPanel.Title = 'PREDICTAION';
            app.MaximumPoreSizeEditFieldLabel = uilabel(app.PREDICTAIONPanel);
            app.MaximumPoreSizeEditFieldLabel.Text = 'Maximum Pore Size';
            app.MaximumPoreSizeEditField = uieditfield(app.PREDICTAIONPanel, 'numeric');
            app.MaximumPoreSizeEditField.Limits = [0 Inf];
            app.MaximumPoreSizeEditField.RoundFractionalValues = 'on';
            app.MaximumPoreSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumPoreSizeEditFieldValueChanged, true);
            app.MaximumPoreSizeEditField.Value = 40;
            app.SegmentationThresholdEditFieldLabel = uilabel(app.PREDICTAIONPanel);
            app.SegmentationThresholdEditFieldLabel.Text = 'Segmentation Threshold';
            app.SegmentationThresholdEditField = uieditfield(app.PREDICTAIONPanel, 'numeric');
            app.SegmentationThresholdEditField.Limits = [0 1];
            app.SegmentationThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumPoreSizeEditFieldValueChanged, true);
            app.SegmentationThresholdEditField.Value = 0.2;
            app.FigurePanel = uipanel(app.PREDICTAIONPanel);
            app.SelectForegroundButton = uibutton(app.UIFigure, 'push');
            app.SelectForegroundButton.ButtonPushedFcn = createCallbackFcn(app, @SelectForegroundButtonPushed, true);
            app.SelectForegroundButton.Icon = 'fa-cut.png';
            app.SelectForegroundButton.Text = 'Select Foreground';
            app.StartTheAnalysisButton = uibutton(app.UIFigure, 'push');
            app.StartTheAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartTheAnalysisButton.Icon = 'fa-gg.png';
            app.StartTheAnalysisButton.Text = 'Start The Analysis';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.LightBulbDropDownLabel = uilabel(app.Panel);
            app.LightBulbDropDownLabel.Text = 'Light Bulb';
            app.LightBulbDropDown = uidropdown(app.Panel);
            app.LightBulbDropDown.Items = {'Maximum Birghtness', 'Manual ROI', 'Histogram'};
            app.LightBulbDropDown.ValueChangedFcn = createCallbackFcn(app, @LightBulbDropDownValueChanged, true);
            app.LightBulbDropDown.Value = 'Maximum Birghtness';
            app.SelectLightBulbAreaButton = uibutton(app.Panel, 'push');
            app.SelectLightBulbAreaButton.ButtonPushedFcn = createCallbackFcn(app, @SelectLightBulbAreaButtonPushed, true);
            app.SelectLightBulbAreaButton.Text = 'Select Light Bulb Area';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end